classdef main_v2_2_savetxt_live_displacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PathEditFieldLabel              matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        LoadFileButton                  matlab.ui.control.Button
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        SelectaxistoanalyseSliderLabel  matlab.ui.control.Label
        SelectaxistoanalyseSlider       matlab.ui.control.Slider
        AnalyseButton                   matlab.ui.control.Button
        Progress                        matlab.ui.control.EditField
        StartOffsetEditFieldLabel       matlab.ui.control.Label
        StartOffsetEditField            matlab.ui.control.NumericEditField
        EndOffsetEditFieldLabel         matlab.ui.control.Label
        EndOffsetEditField              matlab.ui.control.NumericEditField
        SlicesEditFieldLabel            matlab.ui.control.Label
        SlicesEditField                 matlab.ui.control.NumericEditField
        RotateButton                    matlab.ui.control.Button
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        PixelsizeumEditFieldLabel       matlab.ui.control.Label
        PixelsizeumEditField            matlab.ui.control.NumericEditField
        SecondsframeEditFieldLabel      matlab.ui.control.Label
        SecondsframeEditField           matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        loaded='False' % Description
        v
        image_width
        image_height
        framepos=1
        x_pos=1
        scale=1
        start_offset=0
        end_offset=0
        path
        number_of_frames
        slices=0
        rotate=1
        pixeltomum=0.65
        dt=30

    end

    methods (Access = public)
    
        function [final_max,final_min] = getpos(app,frame,v,scale,halfslice)
            %Scale picture
            %scale=2;
            picture=frame(:,:,2);
            picture_scale=imresize(picture, scale);
            
            
            %Use gauss filter
            picture_scale=imgaussfilt(picture_scale,6*scale);


            %Determin Horizontal center
            center=int32(v.width /2)*scale;
            picture_scale=double(picture_scale);
            ax_analyse=app.x_pos*scale
            
            %Calculate edge for 11*scale pixels
            positions=[];
            for i=-halfslice*scale:+halfslice*scale
                
                histo1=double(picture_scale(:,ax_analyse+i));
                [cmax,cmin]=fithisto(histo1);
                positions=cat(1,positions,[cmax,cmin]);
            end
            %%
            %Calculate back original position
            final_max=mean(positions(:,1))/scale;
            final_min=mean(positions(:,2))/scale;
            
            function [cmax,cmin] = fithisto(histo1)
            
            len=size(histo1,1);
            
            gradient1=gradient(histo1);
            [maxval,maxpos]=max(gradient1);
            [minval,minpos]=min(gradient1);
            
            %determine fit Area. Take +-100 from max/min positioj and make sure that boundary is not exceeded
            max_low=maxpos-50;
            if (max_low<0) 
                max_low=1;
            end
            max_up=maxpos+50;
            if (max_up)>len 
                max_up=len;
            end
                
            min_low=minpos-50;
            if (min_low<0) 
                min_low=1;
            end
            min_up=minpos+50;
            if (min_up)>len 
                min_up=len;
            end


            %%
            %Do some fitting
            x_arr=linspace(1,len,len);
            gaussEqn = 'a*exp(-((x-b)/c)^2)';
            f1 = fit(x_arr.',abs(gradient1),gaussEqn,'Start',[maxval maxpos 10 ]);
            f2 = fit(x_arr.',abs(gradient1),gaussEqn,'Start',[-minval minpos 10 ]);
            cmax=f1.b;
            cmin=f2.b;
            
            %plot results live
            cla(app.UIAxes2);
            hold(app.UIAxes2,'on');
            app.UIAxes2.XLim=[400 600];
            
            plot(app.UIAxes2,abs(gradient1),'b-');
            plot(app.UIAxes2,x_arr,f1.a*exp(-((x_arr-f1.b)/f1.c).^2),'r-');
            plot(app.UIAxes2,x_arr,f2.a*exp(-((x_arr-f2.b)/f2.c).^2),'g-');
                     
            
            end
            
            end
                    
        end

    methods (Access = private)
    
        function plot_diffhisto(app)
            %Scale picture
            scale=app.scale;
            frame=read(app.v,app.framepos);
            if app.rotate==-1
                frame=permute(frame,[2,1,3]);
            end
            picture=frame(:,:,2);
            picture_scale=imresize(picture, scale);
            
            
            %Do gau? filter
            picture_scale=imgaussfilt(picture_scale,6*scale);


            %Determin Horizontal center
            center=app.x_pos*scale;
            picture_scale=double(picture_scale);
            ax_analyse=app.x_pos*scale
            histo1=double(picture_scale(:,ax_analyse));  %Changed this
            gradient1=gradient(histo1);
            plot(app.UIAxes2,gradient1)
            app.UIAxes2.NextPlot='add';
            %Also show eestimated max
            [cmax,cmin]=fithisto(histo1);
          
            plot(app.UIAxes2,cmax,5,'o');
            plot(app.UIAxes2,cmin,5,'o');
            app.UIAxes2.NextPlot='replace';
            
            
            function [cmax,cmin] = fithisto(histo1)
            
            len=size(histo1,1);
            
            gradient1=gradient(histo1);
            [maxval,maxpos]=max(gradient1);
            [minval,minpos]=min(gradient1);
            
            %determine fit Area. Take +-100 from max/min positioj and make sure that boundary is not exceeded
            max_low=maxpos-100;
            if (max_low<0) 
                max_low=1;
            end
            max_up=maxpos+100;
            if (max_up)>len 
                max_up=len;
            end
                
            min_low=minpos-100;
            if (min_low<0) 
                min_low=1;
            end
            min_up=minpos+100;
            if (min_up)>len 
                min_up=len;
            end


            %%
            %Do some fitting
            x_arr=linspace(1,len,len);
            gaussEqn = 'a*exp(-((x-b)/c)^2)+d';
            f1 = fit(x_arr(max_low:max_up).',gradient1(max_low:max_up),gaussEqn,'Start',[maxval 0 10 0]);
            f2 = fit(x_arr(min_low:min_up).',gradient1(min_low:min_up),gaussEqn,'Start',[-minval 0 10 0]);
            cmax=f1.b+maxpos;
            cmin=f2.b+minpos;
            end


        end
    
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [name,path]=uigetfile('*')
            app.PathEditField.Value=path;
            app.path=path;
            app.rotate=1

            fpath=strcat(path,name);
            app.v = VideoReader(fpath);

            app.image_width=app.v.width
            app.image_height=app.v.height
            
            number_of_frames=app.v.FrameRate*app.v.Duration
            frame=read(app.v,1);
            imshow(frame, 'parent', app.UIAxes);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[app.x_pos,app.x_pos],[1,app.image_height],'b-')
            hold(app.UIAxes,'off')
            app.FrameSlider.Limits=[1,double(number_of_frames)];
            app.loaded='True'
            app.number_of_frames=int32(number_of_frames)
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            framepos = int8(app.FrameSlider.Value);
            app.framepos=framepos
            if app.loaded=='True'
                frame=read(app.v,framepos);
            if app.rotate==-1
                frame=permute(frame,[2,1,3]);
            end                
                imshow(frame, 'parent', app.UIAxes);
                hold(app.UIAxes,'on')
                plot(app.UIAxes,[app.x_pos,app.x_pos],[1,app.image_height],'b-')
                hold(app.UIAxes,'off')
                plot_diffhisto(app);
            end
        end

        % Value changing function: SelectaxistoanalyseSlider
        function SelectaxistoanalyseSliderValueChanging(app, event)
            xpos_slider = event.Value;
            
            x_pos=int32(app.image_width*xpos_slider/100)
            app.x_pos=x_pos
            
            
            frame=read(app.v,app.framepos);
            if app.rotate==-1
                frame=permute(frame,[2,1,3]);
            end
            imshow(frame, 'parent', app.UIAxes);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[x_pos,x_pos],[1,app.image_height],'b-')
            hold(app.UIAxes,'off')
            plot_diffhisto(app);
            

        end

        % Button pushed function: AnalyseButton
        function AnalyseButtonPushed(app, event)
            number_of_frames=app.number_of_frames
            positions=[];
            for i=1+app.start_offset :number_of_frames-app.end_offset

                
                frame=read(app.v,i);
                if app.rotate==-1
                    frame=permute(frame,[2,1,3]);
                end
                app.Progress.Value=strcat('(',num2str(i),'/',num2str(number_of_frames),')');
                [pos_max,pos_min]=getpos(app,frame,app.v,app.scale,app.slices);
                positions=cat(1,positions,[pos_max,pos_min]);
               
                
            end
            %% Plot results
            figure()
            n=size(positions,1);
           % dt=1./30; %toronto
           % dt=30; %
            
            %Time and size
            time=linspace(0,n-1,n)*app.dt; 
         
           % pixeltomum=1.22 %um/px; toronto
           % pixeltomum=0.325 %um/px;  spinning disk muenster 20x
           % pixeltomum=0.65 %um/px;  spinning disk muenster 10x
            
            %Set to zero
            displacement_up=positions(:,1)-positions(1,1);
            if abs(max(displacement_up))<abs(min(displacement_up))
                displacement_up=displacement_up*-1
            end
            
            displacement_down=positions(:,2)-positions(1,2);
            if abs(max(displacement_down))<abs(min(displacement_down))
                displacement_down=displacement_down*-1;
            end
            
            %Calculate force and displacement
            displacement_up=displacement_up*app.pixeltomum;
            displacement_down=displacement_down*app.pixeltomum;
            
            spring_constant=0.039; %mN/um
            force_up=displacement_up*spring_constant;
            force_down=displacement_down*spring_constant;
            
            time=time';
            
            
            %PLot displacement
            figure()
            plot(time,displacement_up,'b-');
            xlabel('Time [s]');
            ylabel('Displacement [\mum]','interpreter','Tex');
            saveas(gcf,strcat(app.path,'\',sprintf('displacement_up.png')));
            
            figure()
            plot(time,displacement_down,'b-');
            xlabel('Time [s]');
            ylabel('Displacement [\mum]','interpreter','Tex');
            saveas(gcf,strcat(app.path,'\',sprintf('displacement_down.png')));
            
            
            %Plot force
            
            figure();
            plot(time,force_up,'b-')
            xlabel('Time [s]')
            ylabel('Force [mN]','interpreter','Tex')
            saveas(gcf,strcat(app.path,'\',sprintf('force_up.png')));
            
            figure();
            plot(time,force_down,'b-')
            xlabel('Time [s]')
            ylabel('Force [mN]','interpreter','Tex')
            saveas(gcf,strcat(app.path,'\',sprintf('force_low.png')));


            %Write to mat file
            matpath=strcat(app.path,'\results.mat');
            save(matpath)
            
            %Write to text
            tablename=strcat(app.path,'\results.txt');
            T=table(time,displacement_down,force_down,displacement_up,force_up);
            writetable(T,tablename,'Delimiter','\t')


        end

        % Value changed function: StartOffsetEditField
        function StartOffsetEditFieldValueChanged(app, event)
            app.start_offset = app.StartOffsetEditField.Value;
            
        end

        % Value changed function: EndOffsetEditField
        function EndOffsetEditFieldValueChanged(app, event)
            app.end_offset = app.EndOffsetEditField.Value;
            
        end

        % Value changed function: SlicesEditField
        function SlicesEditFieldValueChanged(app, event)
            app.slices = app.SlicesEditField.Value;
            
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed2(app, event)
            
            app.rotate=app.rotate*(-1)
            app.EditField.Value=num2str(app.rotate)
            temp=app.image_width
            app.image_width=app.image_height
            app.image_height=temp
            
            frame=read(app.v,app.framepos);
            if app.rotate==-1
                frame=permute(frame,[2,1,3]);
            end
            imshow(frame, 'parent', app.UIAxes);
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[app.x_pos,app.x_pos],[1,app.image_height],'b-')
            hold(app.UIAxes,'off')
            plot_diffhisto(app);
        end

        % Value changed function: PixelsizeumEditField
        function PixelsizeumEditFieldValueChanged(app, event)
            app.pixeltomum = app.PixelsizeumEditField.Value;
        end

        % Value changed function: SecondsframeEditField
        function SecondsframeEditFieldValueChanged(app, event)
            app.dt = app.SecondsframeEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.SelectaxistoanalyseSliderLabel = uilabel(app.UIFigure);
            app.SelectaxistoanalyseSliderLabel.Text = 'Select axis to analyse';
            app.SelectaxistoanalyseSlider = uislider(app.UIFigure);
            app.SelectaxistoanalyseSlider.ValueChangingFcn = createCallbackFcn(app, @SelectaxistoanalyseSliderValueChanging, true);
            app.AnalyseButton = uibutton(app.UIFigure, 'push');
            app.AnalyseButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyseButtonPushed, true);
            app.AnalyseButton.Text = 'Analyse';
            app.Progress = uieditfield(app.UIFigure, 'text');
            app.StartOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.StartOffsetEditFieldLabel.Text = 'Start Offset';
            app.StartOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @StartOffsetEditFieldValueChanged, true);
            app.EndOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.EndOffsetEditFieldLabel.Text = 'End Offset';
            app.EndOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndOffsetEditField.ValueChangedFcn = createCallbackFcn(app, @EndOffsetEditFieldValueChanged, true);
            app.SlicesEditFieldLabel = uilabel(app.UIFigure);
            app.SlicesEditFieldLabel.Text = 'Slices';
            app.SlicesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SlicesEditField.ValueChangedFcn = createCallbackFcn(app, @SlicesEditFieldValueChanged, true);
            app.RotateButton = uibutton(app.UIFigure, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed2, true);
            app.RotateButton.Text = 'Rotate';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.PixelsizeumEditFieldLabel = uilabel(app.UIFigure);
            app.PixelsizeumEditFieldLabel.Text = 'Pixel size [um]';
            app.PixelsizeumEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelsizeumEditField.ValueChangedFcn = createCallbackFcn(app, @PixelsizeumEditFieldValueChanged, true);
            app.PixelsizeumEditField.Value = 0.65;
            app.SecondsframeEditFieldLabel = uilabel(app.UIFigure);
            app.SecondsframeEditFieldLabel.Text = 'Seconds/frame';
            app.SecondsframeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondsframeEditField.ValueChangedFcn = createCallbackFcn(app, @SecondsframeEditFieldValueChanged, true);
            app.SecondsframeEditField.Value = 30;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Frame')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Gradient of histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_v2_2_savetxt_live_displacement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end